classdef Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ANALYSISPanel      matlab.ui.container.Panel
        STARTButton        matlab.ui.control.Button
        RETURNButton       matlab.ui.control.Button
        IMAGE2Button       matlab.ui.control.Button
        IMAGE1Button       matlab.ui.control.Button
        UACITextAreaLabel  matlab.ui.control.Label
        UACITextArea       matlab.ui.control.TextArea
        SSIMTextAreaLabel  matlab.ui.control.Label
        SSIMTextArea       matlab.ui.control.TextArea
        MSETextAreaLabel   matlab.ui.control.Label
        MSETextArea        matlab.ui.control.TextArea
        PSNRTextAreaLabel  matlab.ui.control.Label
        PSNRTextArea       matlab.ui.control.TextArea
        IMAGE1Label        matlab.ui.control.Label
        IMAGE2Label        matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder_path_R = 'E:\MatLab\WORKSPACE\myproject';
        folder_path_W = 'E:\MatLab\WORKSPACE\myproject\embeded_image';
        image_1;
        image_2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot, 'ScreenSize'); % [x, y, width, height]

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            centerX = (screenSize(3) - appWidth) / 2;
            centerY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [centerX, centerY];

        end

        % Button pushed function: IMAGE1Button
        function IMAGE1ButtonPushed(app, event)
            cla(app.UIAxes);

            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*.bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            if isequal(fname, 0) || isequal(selected, 0)
                disp('Người dùng đã hủy việc chọn ảnh.');
                return;
            end
            
            app.image_1 = imread(fullfile(selected, fname));
            imshow(app.image_1,[], 'Parent', app.UIAxes);
            
        end

        % Button pushed function: IMAGE2Button
        function IMAGE2ButtonPushed(app, event)
            cla(app.UIAxes_2);

            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*.bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            if isequal(fname, 0) || isequal(selected, 0)
                disp('Người dùng đã hủy việc chọn ảnh.');
                return;
            end
            
            app.image_2 = imread(fullfile(selected, fname));
            imshow(app.image_2,[], 'Parent', app.UIAxes_2);
            
        end

        % Button pushed function: RETURNButton
        function RETURNButtonPushed(app, event)
            MAIN_APP();
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.MSETextArea.Value = '0';
            app.PSNRTextArea.Value = '0';
            app.SSIMTextArea.Value = '0';
            app.UACITextArea.Value = '0';
            app.MSETextArea.FontSize = 20;
            app.PSNRTextArea.FontSize = 20;
            app.SSIMTextArea.FontSize = 20;
            app.UACITextArea.FontSize = 20;
            
            
            app.image_1 = uint8(app.image_1);
            app.image_2 = uint8(app.image_2);
            
            MSE = immse(app.image_1, app.image_2);
            PSNR = psnr(app.image_1, app.image_2);
            SSIM = ssim(app.image_1, app.image_2);
            
            [M, N] = size(app.image_1);  

            % Tính toán tổng số pixel
            n = M * N;  
            
            % Tính toán UACI bằng phép toán vector
            UACI = sum(abs(double(app.image_1(:)) - double(app.image_2(:)))) / n / 255;
            
            
            app.MSETextArea.Value = num2str(MSE);
            app.PSNRTextArea.Value = num2str(PSNR);
            app.SSIMTextArea.Value = num2str(SSIM);
            app.UACITextArea.Value = num2str(UACI);
            
        end

        % Value changed function: MSETextArea
        function MSETextAreaValueChanged(app, event)
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.IMAGE2Label = uilabel(app.UIFigure);
            app.IMAGE2Label.Text = 'IMAGE 2';
            app.IMAGE1Label = uilabel(app.UIFigure);
            app.IMAGE1Label.Text = 'IMAGE 1';
            app.PSNRTextArea = uitextarea(app.UIFigure);
            app.PSNRTextAreaLabel = uilabel(app.UIFigure);
            app.PSNRTextAreaLabel.Text = 'PSNR';
            app.MSETextArea = uitextarea(app.UIFigure);
            app.MSETextArea.ValueChangedFcn = createCallbackFcn(app, @MSETextAreaValueChanged, true);
            app.MSETextAreaLabel = uilabel(app.UIFigure);
            app.MSETextAreaLabel.Text = 'MSE';
            app.SSIMTextArea = uitextarea(app.UIFigure);
            app.SSIMTextAreaLabel = uilabel(app.UIFigure);
            app.SSIMTextAreaLabel.Text = 'SSIM';
            app.UACITextArea = uitextarea(app.UIFigure);
            app.UACITextAreaLabel = uilabel(app.UIFigure);
            app.UACITextAreaLabel.Text = 'UACI';
            app.ANALYSISPanel = uipanel(app.UIFigure);
            app.ANALYSISPanel.Title = 'ANALYSIS';
            app.IMAGE1Button = uibutton(app.ANALYSISPanel, 'push');
            app.IMAGE1Button.ButtonPushedFcn = createCallbackFcn(app, @IMAGE1ButtonPushed, true);
            app.IMAGE1Button.Text = 'IMAGE 1';
            app.IMAGE2Button = uibutton(app.ANALYSISPanel, 'push');
            app.IMAGE2Button.ButtonPushedFcn = createCallbackFcn(app, @IMAGE2ButtonPushed, true);
            app.IMAGE2Button.Text = 'IMAGE 2';
            app.RETURNButton = uibutton(app.ANALYSISPanel, 'push');
            app.RETURNButton.ButtonPushedFcn = createCallbackFcn(app, @RETURNButtonPushed, true);
            app.RETURNButton.Text = 'RETURN';
            app.STARTButton = uibutton(app.ANALYSISPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end