classdef Correlation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IMAGE2Label                matlab.ui.control.Label
        CorrelationEditField       matlab.ui.control.NumericEditField
        CorrelationEditFieldLabel  matlab.ui.control.Label
        CORRELATIONPanel           matlab.ui.container.Panel
        IMAGE2Button               matlab.ui.control.Button
        STARTButton                matlab.ui.control.Button
        RETURNButton               matlab.ui.control.Button
        IMAGE1Button               matlab.ui.control.Button
        IMAGE1Label                matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder_path_R = 'E:\MatLab\WORKSPACE\myproject';
        folder_path_W = 'E:\MatLab\WORKSPACE\myproject\embeded_image';
        image_1;
        image_2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot, 'ScreenSize'); % [x, y, width, height]

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            centerX = (screenSize(3) - appWidth) / 2;
            centerY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [centerX, centerY];
    
        end

        % Button pushed function: IMAGE1Button
        function IMAGE1ButtonPushed(app, event)
            cla(app.UIAxes);

            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*.bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            if isequal(fname, 0) || isequal(selected, 0)
                disp('Người dùng đã hủy việc chọn ảnh.');
                return;
            end
            
            app.image_1 = imread(fullfile(selected, fname));
            imshow(app.image_1,[], 'Parent', app.UIAxes);
            
        end

        % Callback function
        function IMAGE2ButtonPushed(app, event)
            
        end

        % Button pushed function: RETURNButton
        function RETURNButtonPushed(app, event)
            MAIN_APP();
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.CorrelationEditField.Value = 0;
            app.CorrelationEditField.FontSize = 20;
            col = analyse_correlation(app.image_1, app.image_2);
            
            app.CorrelationEditField.Value = col;

        end

        % Button pushed function: IMAGE2Button
        function IMAGE2ButtonPushed2(app, event)
            cla(app.UIAxes_2);

            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*.bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            if isequal(fname, 0) || isequal(selected, 0)
                disp('Người dùng đã hủy việc chọn ảnh.');
                return;
            end
            
            app.image_2 = imread(fullfile(selected, fname));
            imshow(app.image_2,[], 'Parent', app.UIAxes_2);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.IMAGE1Label = uilabel(app.UIFigure);
            app.IMAGE1Label.Text = 'IMAGE 1';
            app.CORRELATIONPanel = uipanel(app.UIFigure);
            app.CORRELATIONPanel.Title = 'CORRELATION';
            app.IMAGE1Button = uibutton(app.CORRELATIONPanel, 'push');
            app.IMAGE1Button.ButtonPushedFcn = createCallbackFcn(app, @IMAGE1ButtonPushed, true);
            app.IMAGE1Button.Text = 'IMAGE 1';
            app.RETURNButton = uibutton(app.CORRELATIONPanel, 'push');
            app.RETURNButton.ButtonPushedFcn = createCallbackFcn(app, @RETURNButtonPushed, true);
            app.RETURNButton.Text = 'RETURN';
            app.STARTButton = uibutton(app.CORRELATIONPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.IMAGE2Button = uibutton(app.CORRELATIONPanel, 'push');
            app.IMAGE2Button.ButtonPushedFcn = createCallbackFcn(app, @IMAGE2ButtonPushed2, true);
            app.IMAGE2Button.Text = 'IMAGE 2';
            app.CorrelationEditFieldLabel = uilabel(app.UIFigure);
            app.CorrelationEditFieldLabel.Text = 'Correlation';
            app.CorrelationEditField = uieditfield(app.UIFigure, 'numeric');
            app.IMAGE2Label = uilabel(app.UIFigure);
            app.IMAGE2Label.Text = 'IMAGE 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Correlation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end