classdef Embed_watermark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MENUPanel              matlab.ui.container.Panel
        ReturnButton           matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        EmbedButton            matlab.ui.control.Button
        SelectWatermarkButton  matlab.ui.control.Button
        SelectImageButton      matlab.ui.control.Button
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder_path_R = 'E:\MatLab\WORKSPACE\myproject\photos';
        folder_path_L = 'E:\MatLab\WORKSPACE\myproject\logo';
        folder_path_W = 'E:\MatLab\WORKSPACE\myproject\embeded_image';
        original_image;
        watermark_logo;
        embedded_image;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot, 'ScreenSize'); % [x, y, width, height]

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            centerX = (screenSize(3) - appWidth) / 2;
            centerY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [centerX, centerY];
            
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            app.original_image = imread(fullfile(selected, fname));
            imshow(app.original_image,[], 'Parent', app.UIAxes);
        end

        % Button pushed function: SelectWatermarkButton
        function SelectWatermarkButtonPushed(app, event)
            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*bmp'}, 'Chọn ảnh', app.folder_path_L); % Chọn ảnh
            app.watermark_logo = imread(fullfile(selected, fname));
            imshow(app.watermark_logo,[], 'Parent', app.UIAxes_3);
        end

        % Button pushed function: EmbedButton
        function EmbedButtonPushed(app, event)
            app.embedded_image = embed_watermark_general(app.original_image, app.watermark_logo);
            imshow(app.embedded_image,[], 'Parent', app.UIAxes_2);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global var;

            % Chọn nơi lưu tệp ảnh
            [filename, app.folder_path_W, filterindex] = uiputfile({
                '*.png;*.bmp', 'Định dạng không nén (PNG, BMP)';
                '*.png', 'PNG files (*.png)';
                '*.bmp', 'Bitmap files (*.bmp)'
            }, 'Lưu ảnh giữ nguyên dữ liệu nhị phân', app.folder_path_W);
            
            % Kiểm tra nếu người dùng không hủy chọn
            if isequal(filename, 0) || isequal(app.folder_path_W, 0)
                disp('Người dùng đã hủy chọn ảnh.');
            else
                % Tạo đường dẫn đầy đủ của tệp ảnh
                var = fullfile(app.folder_path_W, filename);
            
               % Xác nhận kiểu dữ liệu ảnh (phải là uint8 hoặc uint16)
                if ~isa(app.embedded_image, 'uint8') && ~isa(app.embedded_image, 'uint16')
                    error('Ảnh cần phải ở định dạng uint8 hoặc uint16 để giữ nguyên giá trị nhị phân.');
                end
                
                % Lưu ảnh dưới định dạng không nén
                imwrite(app.embedded_image, var); % PNG hoặc BMP
            
                % Thông báo thành công
                disp(['Ảnh đã được lưu thành công tại: ', var]);
            end

        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            MAIN_APP();
            app.UIFigure.Visible = 'off'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Embeded Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Watermark')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.MENUPanel = uipanel(app.UIFigure);
            app.MENUPanel.Title = 'MENU';
            app.SelectImageButton = uibutton(app.MENUPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.SelectWatermarkButton = uibutton(app.MENUPanel, 'push');
            app.SelectWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWatermarkButtonPushed, true);
            app.SelectWatermarkButton.Text = 'Select Watermark';
            app.EmbedButton = uibutton(app.MENUPanel, 'push');
            app.EmbedButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedButtonPushed, true);
            app.EmbedButton.Text = 'Embed';
            app.SaveButton = uibutton(app.MENUPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ReturnButton = uibutton(app.MENUPanel, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Embed_watermark

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end