classdef Encode_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ENCODEButton       matlab.ui.control.Button
        SELECTIMAGEButton  matlab.ui.control.Button
        RETURNButton       matlab.ui.control.Button
        SAVEButton         matlab.ui.control.Button
        TextArea_2         matlab.ui.control.TextArea
        TextArea           matlab.ui.control.TextArea
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        folder_path_R = 'E:\MatLab\WORKSPACE\myproject\photos';
        folder_path_W = 'E:\MatLab\WORKSPACE\myproject\encoded_photo';
        original_image
        encrypted_image_1;
        encrypted_image_2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot, 'ScreenSize'); % [x, y, width, height]

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            centerX = (screenSize(3) - appWidth) / 2;
            centerY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [centerX, centerY];
       
        end

        % Button pushed function: SELECTIMAGEButton
        function SELECTIMAGEButtonPushed(app, event)

            % Chọn và nhập ảnh
            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            app.original_image = imread(fullfile(selected, fname));
            imshow(app.original_image, [], 'Parent', app.UIAxes);
        end

        % Button pushed function: ENCODEButton
        function ENCODEButtonPushed(app, event)
            if size(app.original_image, 3) == 3
                app.encrypted_image_1 = RGB_encode(app.original_image, 1); %mã hóa lần 1
                app.encrypted_image_2 = RGB_encode(app.encrypted_image_1, 2); %mã hóa lần 2
                % Hiển thị kết quả mã hóa ảnh RGB
                imshow(app.encrypted_image_2, 'Parent', app.UIAxes_2);
                
            else size(app.original_image, 3) == 1
                % Nếu ảnh là ảnh xám (grayscale), kiểm tra các giá trị xám
                grayscale_image = app.original_image; % Ảnh xám

                % Mã hóa ảnh xám
                app.encrypted_image_1 = uint8(chaotic_encode(grayscale_image, 1, 0));  % Mã hóa lần 1 
                app.encrypted_image_2  = uint8(chaotic_encode(app.encrypted_image_1, 2, 0));  % Mã hóa lần 2
  
                % Hiển thị kết quả mã hóa ảnh xám
               imshow(app.encrypted_image_2, [] , 'Parent', app.UIAxes_2);
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            global var;

            % Chọn nơi lưu tệp ảnh
            [filename, app.folder_path_W, filterindex] = uiputfile({
                '*.png;*.bmp', 'Định dạng không nén (PNG, BMP)';
                '*.png', 'PNG files (*.png)';
                '*.bmp', 'Bitmap files (*.bmp)'
            }, 'Lưu ảnh giữ nguyên dữ liệu nhị phân', app.folder_path_W);
            
            % Kiểm tra nếu người dùng không hủy chọn
            if isequal(filename, 0) || isequal(app.folder_path_W, 0)
                disp('Người dùng đã hủy chọn ảnh.');
            else
                % Tạo đường dẫn đầy đủ của tệp ảnh
                var = fullfile(app.folder_path_W, filename);
            
                  
               % Xác nhận kiểu dữ liệu ảnh (phải là uint8 hoặc uint16)
                if ~isa(app.encrypted_image_1, 'uint8') && ~isa(app.encrypted_image_1, 'uint16')
                    error('Ảnh cần phải ở định dạng uint8 hoặc uint16 để giữ nguyên giá trị nhị phân.');
                end
                
                % Lưu ảnh đã mã hóa vào tệp
                imwrite(app.encrypted_image_2, var);  % Lưu ảnh vào tệp
            
                % Thông báo thành công
                disp(['Ảnh đã được lưu thành công tại: ', var]);
            end
        end

        % Button pushed function: RETURNButton
        function RETURNButtonPushed(app, event)
            MAIN_APP();
            app.UIFigure.Visible = 'off'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Original Image'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Encoded Image'};
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.RETURNButton = uibutton(app.UIFigure, 'push');
            app.RETURNButton.ButtonPushedFcn = createCallbackFcn(app, @RETURNButtonPushed, true);
            app.RETURNButton.Text = 'RETURN';
            app.SELECTIMAGEButton = uibutton(app.UIFigure, 'push');
            app.SELECTIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTIMAGEButtonPushed, true);
            app.SELECTIMAGEButton.Text = 'SELECT IMAGE';
            app.ENCODEButton = uibutton(app.UIFigure, 'push');
            app.ENCODEButton.ButtonPushedFcn = createCallbackFcn(app, @ENCODEButtonPushed, true);
            app.ENCODEButton.Text = 'ENCODE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Encode_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end