classdef Extrac_watermark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        MENUPanel          matlab.ui.container.Panel
        ReturnButton       matlab.ui.control.Button
        SaveButton         matlab.ui.control.Button
        ExtractButton      matlab.ui.control.Button
        SelectImageButton  matlab.ui.control.Button
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        folder_path_R = 'E:\MatLab\WORKSPACE\myproject\embeded_image';
        folder_path_W = 'E:\MatLab\WORKSPACE\myproject\logo';
        watermark_logo;
        enbeded_image;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot, 'ScreenSize'); % [x, y, width, height]

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            centerX = (screenSize(3) - appWidth) / 2;
            centerY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [centerX, centerY];
      
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            app.enbeded_image = imread(fullfile(selected, fname));
            imshow(app.enbeded_image, [], 'Parent', app.UIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global var;

           % Chọn nơi lưu tệp ảnh
            [filename, app.folder_path_W, filterindex] = uiputfile({
                '*.png;*.bmp', 'Định dạng không nén (PNG, BMP)';
                '*.png', 'PNG files (*.png)';
                '*.bmp', 'Bitmap files (*.bmp)'
            }, 'Lưu ảnh giữ nguyên dữ liệu nhị phân', app.folder_path_W);
            
            % Kiểm tra nếu người dùng không hủy chọn
            if isequal(filename, 0) || isequal(app.folder_path_W, 0)
                disp('Người dùng đã hủy chọn ảnh.');
            else
                % Tạo đường dẫn đầy đủ của tệp ảnh
                var = fullfile(app.folder_path_W, filename);
            
                % Lưu ảnh đã mã hóa vào tệp
                imwrite(uint8(app.watermark_logo), var);  % Lưu ảnh vào tệp
            
                % Thông báo thành công
                disp(['Ảnh đã được lưu thành công tại: ', var]);
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            MAIN_APP();
            app.UIFigure.Visible = 'off'
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            app.watermark_logo = extract_watermark_general(app.enbeded_image);
            imshow(app.watermark_logo, [], 'Parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'IMAGE')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'WATERMARK')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.MENUPanel = uipanel(app.UIFigure);
            app.MENUPanel.Title = 'MENU';
            app.SelectImageButton = uibutton(app.MENUPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ExtractButton = uibutton(app.MENUPanel, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.SaveButton = uibutton(app.MENUPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ReturnButton = uibutton(app.MENUPanel, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Extrac_watermark

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end