classdef MAIN_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CorrelationButton       matlab.ui.control.Button
        AnalysisButton          matlab.ui.control.Button
        TextArea2               matlab.ui.control.TextArea
        ExtractWatermarkButton  matlab.ui.control.Button
        ImageDecodingButton     matlab.ui.control.Button
        ShowHistogramButton     matlab.ui.control.Button
        ImageEncodingButton     matlab.ui.control.Button
        EmbededWatermarkButton  matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        TextArea                matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot, 'ScreenSize'); % [x, y, width, height]

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            centerX = (screenSize(3) - appWidth) / 2;
            centerY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [centerX, centerY];
            app.UIFigure.WindowStyle = 'alwaysontop';
        end

        % Button pushed function: ImageEncodingButton
        function ImageEncodingButtonPushed(app, event)
            Encode_GUI();
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: ImageDecodingButton
        function ImageDecodingButtonPushed(app, event)
            Decode_GUI();
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: EmbededWatermarkButton
        function EmbededWatermarkButtonPushed(app, event)
            Embed_watermark();
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
            Show_histogram(); 
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: ExtractWatermarkButton
        function ExtractWatermarkButtonPushed(app, event)
            Extrac_watermark();
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            Analysis();
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: CorrelationButton
        function CorrelationButtonPushed(app, event)
            Correlation();
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'BÁO CÁO CHUYÊN ĐỀ KỸ NGHỆ '; 'NGHIÊN CỨU HỆ THỐNG HỖN LOẠN CẢI TIẾN ỨNG DỤNG CHO MÃ HÓA HÌNH ẢNH VÀ THỦY VÂN SỐ'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'hvmm.PNG';
            app.EmbededWatermarkButton = uibutton(app.UIFigure, 'push');
            app.EmbededWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @EmbededWatermarkButtonPushed, true);
            app.EmbededWatermarkButton.Text = 'Embeded Watermark';
            app.ImageEncodingButton = uibutton(app.UIFigure, 'push');
            app.ImageEncodingButton.ButtonPushedFcn = createCallbackFcn(app, @ImageEncodingButtonPushed, true);
            app.ImageEncodingButton.Text = 'Image Encoding';
            app.ShowHistogramButton = uibutton(app.UIFigure, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Text = 'Show Histogram';
            app.ImageDecodingButton = uibutton(app.UIFigure, 'push');
            app.ImageDecodingButton.ButtonPushedFcn = createCallbackFcn(app, @ImageDecodingButtonPushed, true);
            app.ImageDecodingButton.Text = 'Image Decoding';
            app.ExtractWatermarkButton = uibutton(app.UIFigure, 'push');
            app.ExtractWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractWatermarkButtonPushed, true);
            app.ExtractWatermarkButton.Text = 'Extract Watermark';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Value = {'Trần Nhật Hoàng - AT180518'};
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.CorrelationButton = uibutton(app.UIFigure, 'push');
            app.CorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelationButtonPushed, true);
            app.CorrelationButton.Text = 'Correlation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIN_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end