classdef Show_histogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ReturnButton    matlab.ui.control.Button
        SelectButton_2  matlab.ui.control.Button
        SelectButton    matlab.ui.control.Button
        UIAxes_6        matlab.ui.control.UIAxes
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_5        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder_path_R = 'E:\MatLab\WORKSPACE\myproject';
        folder_path_W = 'E:\MatLab\WORKSPACE\myproject\embeded_image';
        original_image
        enbeded_image;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot, 'ScreenSize'); % [x, y, width, height]

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            centerX = (screenSize(3) - appWidth) / 2;
            centerY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [centerX, centerY];
         
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            cla(app.UIAxes_3)
            cla(app.UIAxes)
            
            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*.bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            if isequal(fname, 0) || isequal(selected, 0)
                disp('Người dùng đã hủy việc chọn ảnh.');
                return;
            end
            
            app.original_image = imread(fullfile(selected, fname));
            imshow(app.original_image,[], 'Parent', app.UIAxes);
            
            if size(app.original_image, 3) == 3
                % RGB
                redChannel = app.original_image(:, :, 1);
                greenChannel = app.original_image(:, :, 2);
                blueChannel = app.original_image(:, :, 3);

                [countsR, binLocations] = imhist(redChannel);
                [countsG, ~] = imhist(greenChannel);
                [countsB, ~] = imhist(blueChannel);
                
                % show image
                axes(app.UIAxes_3); 
                hold(app.UIAxes_3, 'on');
                bar(app.UIAxes_3, binLocations, countsR, 'r', 'DisplayName', 'Red', 'FaceAlpha', 0.5);
                bar(app.UIAxes_3, binLocations, countsG, 'g', 'DisplayName', 'Green', 'FaceAlpha', 0.5);
                bar(app.UIAxes_3, binLocations, countsB, 'b', 'DisplayName', 'Blue', 'FaceAlpha', 0.5);
                hold(app.UIAxes_3, 'off');
                legend(app.UIAxes_3, 'show');
                title(app.UIAxes_3, 'Histogram (RGB)');
            else
                % gray
                [counts, binLocations] = imhist(app.original_image);
                
                % show img
                axes(app.UIAxes_3); % Chọn khung hiển thị histogram
                bar(app.UIAxes_3, binLocations, counts);
                title(app.UIAxes_3, 'Histogram (Gray)');
            end


        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            cla(app.UIAxes_5)
            cla(app.UIAxes_6)
            
            
            [fname, selected] = uigetfile({'*.jpg;*.png;*.tif;*.bmp'}, 'Chọn ảnh', app.folder_path_R); % Chọn ảnh
            if isequal(fname, 0) || isequal(selected, 0)
                disp('Người dùng đã hủy việc chọn ảnh.');
                return;
            end
            
            app.enbeded_image = imread(fullfile(selected, fname));
            imshow(app.enbeded_image,[], 'Parent', app.UIAxes_5);
            
            if size(app.enbeded_image, 3) == 3
                % RGB
                redChannel = app.enbeded_image(:, :, 1);
                greenChannel = app.enbeded_image(:, :, 2);
                blueChannel = app.enbeded_image(:, :, 3);

                [countsR, binLocations] = imhist(redChannel);
                [countsG, ~] = imhist(greenChannel);
                [countsB, ~] = imhist(blueChannel);
                
                % show image
                axes(app.UIAxes_6); 
                hold(app.UIAxes_6, 'on');
                bar(app.UIAxes_6, binLocations, countsR, 'r', 'DisplayName', 'Red', 'FaceAlpha', 0.5);
                bar(app.UIAxes_6, binLocations, countsG, 'g', 'DisplayName', 'Green', 'FaceAlpha', 0.5);
                bar(app.UIAxes_6, binLocations, countsB, 'b', 'DisplayName', 'Blue', 'FaceAlpha', 0.5);
                hold(app.UIAxes_6, 'off');
                legend(app.UIAxes_6, 'show');
                title(app.UIAxes_6, 'Histogram (RGB)');
            else
                % gray
                [counts, binLocations] = imhist(app.enbeded_image);
                
                % show img
                axes(app.UIAxes_6); % Chọn khung hiển thị histogram
                bar(app.UIAxes_6, binLocations, counts);
                title(app.UIAxes_6, 'Histogram (Gray)');
            end
            
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            MAIN_APP();
            app.UIFigure.Visible = 'off'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image 1')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Image 2')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [0 50 100 150 200 250];
            app.UIAxes_3.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.UIAxes_3.YTick = [0 500 1000 1500 2000 2500];
            app.UIAxes_3.YTickLabel = {'0'; '500'; '1000'; '1500'; '2000'; '2500'};
            app.UIAxes_6 = uiaxes(app.UIFigure);
            app.UIAxes_6.XTick = [0 50 100 150 200 250];
            app.UIAxes_6.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.UIAxes_6.YTick = [0 500 1000 1500 2000 2500];
            app.UIAxes_6.YTickLabel = {'0'; '500'; '1000'; '1500'; '2000'; '2500'};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_histogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end